classdef Plotypus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotypusUIFigure              matlab.ui.Figure
        Image                         matlab.ui.control.Image
        BadChannelsJustNoteEditField  matlab.ui.control.EditField
        BadChannelsJustNoteEditFieldLabel  matlab.ui.control.Label
        ChannelsPanel                 matlab.ui.container.Panel
        ApplyButton_3                 matlab.ui.control.Button
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox                      matlab.ui.control.CheckBox
        ColoumnLabel                  matlab.ui.control.Label
        SingleDifferentialCheckBox    matlab.ui.control.CheckBox
        ApplyButton_2                 matlab.ui.control.Button
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        ToLabel                       matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        SignalPanel                   matlab.ui.container.Panel
        FileNameLabel                 matlab.ui.control.Label
        LoadSignalButton              matlab.ui.control.Button
        TimePanel                     matlab.ui.container.Panel
        EditField                     matlab.ui.control.EditField
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        numAllCh
        numEMGCh
        EMGChIdx=[];
        ForceIdx
        sf
        ForceData
        EMGData
        vImage
  
        SelectedSensors

    end
    properties (Access = private)
        WindowSize=2;
        StepSize=1;
        CurrentPointer;  % last sample to draw
        PrevPointer;
    end
    properties (Dependent, Access = private)
        % Example of propert getter


    end

    methods (Access = public)

        function  PlotLoadedData(app)

            nCh=length(app.EMGChIdx);
            SF = app.sf;
            sigLen = length(app.EMGData);
            if app.SingleDifferentialCheckBox.Value==1
                nCh=nCh/2;
                %app.EMGData=
                %app.EMGChIdx=
            end


            % Verify app.CurrentPointer
            if app.CurrentPointer < app.WindowSize
                % Don't allow going before zero.
                app.CurrentPointer = app.WindowSize;
            end
            if app.CurrentPointer > sigLen
                % Don't allow going after end of signal.
                app.CurrentPointer = sigLen;
            end
            
            % Get sample range
            rangeStart = app.CurrentPointer - app.WindowSize + 1;
            if rangeStart < 1
                rangeStart = 1;
            end
            rangeEnd = rangeStart + app.WindowSize - 1;
            if rangeEnd > sigLen
                rangeEnd = sigLen;
            end
            range = rangeStart : rangeEnd;

            app.EditField.Value = num2str( (rangeStart - 1) / SF );

            % Params
            yHeightCh = 10; % units

            %
            tRange = range / SF;


            % We assume yHeightCh vertical units size for every row (channel),
            %   however we give more swing range for each channel.
            % Rational is that channels will touch the extremes less
            %   often, so let's use that space.
            yExtraSwingCh = 0.6 * yHeightCh;
            yTotalSwingCh = yExtraSwingCh + yHeightCh;
            yHeight = yHeightCh * nCh;

            % Find swing center point for each channel
            yOffset = yHeightCh * ((1 : nCh) - 0.5);
            yOffset=double(yOffset);


            %   This is used for normalization.

            nNorm = range;

            % Find normalization factor for various signal subgroups.
            epsilon = 1e-8;
            sigRangeEMG = 0.5 * yTotalSwingCh / max(epsilon, max(abs(app.EMGData(app.EMGChIdx, nNorm)), [], 'all'));
            sigRangeForce=  0.5 * yTotalSwingCh;
            %hold(app.UIAxes, 'off');
            cla(app.UIAxes);
            cla(app.UIAxes2);
            % Draw all channels
            j=1;
            for c = app.EMGChIdx
                yRange = sigRangeEMG;
                y = app.EMGData(c, range) * yRange + yOffset(j);
                plot(app.UIAxes, tRange, y);
                hold(app.UIAxes, 'on');
                j=j+1;
            end

            %For Force Sensor
            yRange = sigRangeForce;
            y = app.ForceData(1, range) * yRange;
            plot(app.UIAxes2, app.ForceData);
            
            xline(app.UIAxes2,rangeStart);
            hold(app.UIAxes2, 'on');
            xline(app.UIAxes2,rangeEnd)


            %plot(app.UIAxes2, tRange, y);
            hold(app.UIAxes2, 'off');


            % Set graph limits
            app.UIAxes.YLim = [-10, yHeight + yExtraSwingCh];
            yticks(app.UIAxes,yOffset);
            yticklabels(app.UIAxes,app.EMGChIdx)


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes3,'visible', 'off');
            app.EMGChIdx=1:10;
            app.vImage = imread("U:\HDEMG\Pilot Recording\electrodes3.jpg");
            imshow(app.vImage, 'Parent', app.UIAxes3);
        

        end

        % Button pushed function: LoadSignalButton
        function LoadSignalButtonPushed(app, event)
            
            [filename, path] = uigetfile('*.mat');

            loadedSig=load(filename);
            AllData=loadedSig.Data{1};
            AllData=AllData';
            app.sf=loadedSig.SamplingFrequency;
            %To be chnanged to a way to fining the pressure sensor properly

            [numallCh,~]=size(AllData);
            app.ForceIdx=numallCh;
            app.numEMGCh=numallCh-1;
            app.EMGData=AllData(1:app.numEMGCh,:);
            app.ForceData=AllData(app.ForceIdx,:);
            app.FileNameLabel.Text=filename;
            app.WindowSize=10*(app.sf);
            app.StepSize=str2double(app.EditField.Value)*(app.sf);
            app.CurrentPointer=app.WindowSize;
            app.PlotLoadedData();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            app.CurrentPointer=app.CurrentPointer+app.StepSize;
            app.PlotLoadedData();

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            app.CurrentPointer=app.CurrentPointer-app.StepSize;
            app.PlotLoadedData();


        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)

            app.CurrentPointer=length(app.EMGData);
            app.PlotLoadedData();

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            app.CurrentPointer=app.WindowSize;
            app.PlotLoadedData();

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

            app.StepSize=str2double(app.EditField.Value)*(app.sf);
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            ChStart=app.EditField_2.Value;
            ChEnd=app.EditField_3.Value;
            app.EMGChIdx=ChStart:ChEnd;
            app.PlotLoadedData();
            app.CheckBox.Value=0;
            app.CheckBox_2.Value=0;
            app.CheckBox_3.Value=0;
            app.CheckBox_4.Value=0;
            app.CheckBox_5.Value=0;
            cla(app.UIAxes3);
            imshow(app.vImage, 'Parent', app.UIAxes3);
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            [rows, columns, ~] = size(app.vImage);
            cla(app.UIAxes3);
            imshow(app.vImage, 'Parent', app.UIAxes3);
            app.EMGChIdx=[];

            if app.CheckBox.Value==1
                app.EMGChIdx=[app.EMGChIdx, 1:12];
                
                rectangle(app.UIAxes3,'Position',[0,0,columns/5,rows],'EdgeColor',[.2,.5,.27],'LineWidth',1.5)
                hold(app.UIAxes3, 'off');
                
            end
            if app.CheckBox_2.Value==1
                app.EMGChIdx=[app.EMGChIdx, 25:-1:13];
                  rectangle(app.UIAxes3,'Position',[columns/5,0,columns/5,rows],'EdgeColor',[.2,.5,.27],'LineWidth',1.5)
            end
            if app.CheckBox_3.Value==1
                app.EMGChIdx=[app.EMGChIdx, 26:38];
                rectangle(app.UIAxes3,'Position',[2*columns/5,0,columns/5,rows],'EdgeColor',[.2,.5,.27],'LineWidth',1.5)
            end
            if app.CheckBox_4.Value==1
                app.EMGChIdx=[app.EMGChIdx, 51:-1:39];
                rectangle(app.UIAxes3,'Position',[3*columns/5,0,columns/5,rows],'EdgeColor',[.2,.5,.27],'LineWidth',1.5)
            end
            if app.CheckBox_5.Value==1
                app.EMGChIdx=[app.EMGChIdx, 52:64];
                rectangle(app.UIAxes3,'Position',[4*columns/5,0,columns/5,rows],'EdgeColor',[.2,.5,.27],'LineWidth',1.5)
            end
            uistack(app.UIAxes3.Children(1), 'top');
            hold(app.UIAxes3, 'off');
            app.PlotLoadedData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlotypusUIFigure = uifigure('Visible', 'off');
            app.PlotypusUIFigure.Name = 'Plotypus';
            app.PlotypusUIFigure.Icon = fullfile(pathToMLAPP, 'Ploty.jpeg');
            app.UIAxes = uiaxes(app.PlotypusUIFigure);
            title(app.UIAxes, 'EMG')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.PlotypusUIFigure);
            title(app.UIAxes2, 'Force')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TimePanel = uipanel(app.PlotypusUIFigure);
            app.TimePanel.Title = 'Time';
            app.Button = uibutton(app.TimePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.TimePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.Button_3 = uibutton(app.TimePanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<<';
            app.Button_4 = uibutton(app.TimePanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>';
            app.EditField = uieditfield(app.TimePanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = '1';
            app.SignalPanel = uipanel(app.PlotypusUIFigure);
            app.SignalPanel.Title = 'Signal';
            app.LoadSignalButton = uibutton(app.SignalPanel, 'push');
            app.LoadSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalButtonPushed, true);
            app.LoadSignalButton.Text = 'Load Signal';
            app.FileNameLabel = uilabel(app.SignalPanel);
            app.FileNameLabel.Text = 'FileName';
            app.ChannelsPanel = uipanel(app.PlotypusUIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.UIAxes3 = uiaxes(app.ChannelsPanel);
            zlabel(app.UIAxes3, 'Z')
            app.ToLabel = uilabel(app.ChannelsPanel);
            app.ToLabel.Text = 'To';
            app.EditField_2 = uieditfield(app.ChannelsPanel, 'numeric');
            app.EditField_2.Value = 1;
            app.EditField_3 = uieditfield(app.ChannelsPanel, 'numeric');
            app.EditField_3.Value = 10;
            app.ApplyButton_2 = uibutton(app.ChannelsPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.SingleDifferentialCheckBox = uicheckbox(app.ChannelsPanel);
            app.SingleDifferentialCheckBox.Text = 'Single Differential';
            app.ColoumnLabel = uilabel(app.ChannelsPanel);
            app.ColoumnLabel.Text = 'Coloumn';
            app.CheckBox = uicheckbox(app.ChannelsPanel);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.ChannelsPanel);
            app.CheckBox_5.Text = '';
            app.ApplyButton_3 = uibutton(app.ChannelsPanel, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.BadChannelsJustNoteEditFieldLabel = uilabel(app.PlotypusUIFigure);
            app.BadChannelsJustNoteEditFieldLabel.Text = 'Bad Channels (Just Note)';
            app.BadChannelsJustNoteEditField = uieditfield(app.PlotypusUIFigure, 'text');
            app.Image = uiimage(app.PlotypusUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Ploty.jpeg');
            % Show the figure after all components are created
            app.PlotypusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plotypus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotypusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotypusUIFigure)
        end
    end
end